classdef RoomTour < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImageNameTextArea        matlab.ui.control.TextArea
        ImageNameTextAreaLabel   matlab.ui.control.Label
        DiscriptionLabel         matlab.ui.control.Label
        Tree                     matlab.ui.container.Tree
        SelectDatasethereButton  matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image_path                                   % path of image
        image_names                                % the name list of the select dataset

        selected_image                               % path of the selected image
        selected_image_name                             % the name of the selected image
        
        basic_points = {0 0 0 0};
        basic_points_count = 0;

        default_image_names = {'metro-station', 'oil-painting', ...
            'sagrada_familia', 'shopping-mall', 'simple-room', 'uhren-turm'};
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions'))
            
            % lock the interaction with difference highlight
            app.UIAxes.Interactions = [];
        end

        % Button pushed function: SelectDatasethereButton
        function SelectDatasethereButtonPushed(app, event)
            GetDatasetList(app)
            % remove the cover
            % app.Image.Visible = false;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(selectedNodes.NodeData)
                % save the selected dataset path
                app.selected_image = selectedNodes.NodeData;
                % get the dataset name
                [~,dirname,~] = fileparts(selectedNodes.NodeData);
                app.selected_image_name = dirname;
%                 % picture processing
%                 ProcessImageData_final(app)
%                 
%                 % difference highlight initialization
%                 DifferenceInitilization(app)
%                 % curtain initialization
%                 CurtainInitialization(app)
%                 % time lapse initialization
%                 TimelapseInitialization(app)
%                 
                % description 
                app.ImageNameTextArea.Value = app.selected_image_name;
                imshow(app.selected_image, 'Parent', app.UIAxes);

                % app.PictureNumberEditField.Value = length(app.current_image_names);
                
            else
                
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.basic_points_count = app.basic_points_count + 1;
            app.basic_points(app.basic_points_count) = event.IntersectionPoint 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'axes1';
            app.SelectDatasethereButton = uibutton(app.UIFigure, 'push');
            app.SelectDatasethereButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatasethereButtonPushed, true);
            app.SelectDatasethereButton.Text = 'Select Dataset here';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.DiscriptionLabel = uilabel(app.UIFigure);
            app.DiscriptionLabel.Text = 'Discription:';
            app.ImageNameTextAreaLabel = uilabel(app.UIFigure);
            app.ImageNameTextAreaLabel.Text = 'Image Name: ';
            app.ImageNameTextArea = uitextarea(app.UIFigure);
            app.ImageNameTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoomTour

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end